classdef Person_Identification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label                         matlab.ui.control.Label
        ClassificationEditField       matlab.ui.control.EditField
        ClassificationEditFieldLabel  matlab.ui.control.Label
        ModelTrainedLabel             matlab.ui.control.Label
        TrainModelButton              matlab.ui.control.Button
        SelectImageButton             matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        SuccesfulAuthorizationEditField  matlab.ui.control.NumericEditField
        SuccesfulAuthorizationEditFieldLabel  matlab.ui.control.Label
        AuthorizeMeButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        authPath % Path of the image that needs to be authorized
        network % CNN Trained
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Image, SelectImageButton
        function SelectImageButtonPushed(app, event)
           
            
            [file,path] = uigetfile("*.jpg");
            app.authPath = strcat(path,"\",file);
            disp(file)
            disp(path)
            app.Image.ImageSource = imread(strcat(path,"\",file));
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)


            % Source Face Recognition Data
            digitDatasetPath = fullfile('C:\Users\goode\Downloads\Face-Images\Face Images\OurFaces');
            % Image Data Store for Face Recognition Data
            imds = imageDatastore(digitDatasetPath, ...
                'IncludeSubfolders',true, ...
                'LabelSource','foldernames');
            
            numTrainFiles = 50;
            [imdsTrain,imdsValidation] = splitEachLabel(imds,numTrainFiles,'randomize');


            inputSize = [200 200 3];
            numClasses = 5;
            layers = [
                imageInputLayer(inputSize)
                convolution2dLayer(200,120)
                batchNormalizationLayer
                reluLayer
                fullyConnectedLayer(numClasses)
                softmaxLayer
                classificationLayer];


            options = trainingOptions('sgdm', ...
            'MaxEpochs',64, ...
            'ValidationData',imdsValidation, ...
            'ValidationFrequency',30, ...
            'Verbose',false, ...
            'Plots','none');
             

            app.network = trainNetwork(imdsTrain,layers,options);
            app.ModelTrainedLabel.BackgroundColor = [0.39,0.95,0.29];


        end

        % Button pushed function: AuthorizeMeButton
        function AuthorizeMeButtonPushed(app, event)
    
            imagePath = app.authPath;
            authImageIMDS = imageDatastore(imagePath);
            [classification, scores] = classify(app.network, authImageIMDS);
            maximumScore = max(scores);
            disp(class(maximumScore))
            disp(classification)
            app.SuccesfulAuthorizationEditField.Value = double(maximumScore);
            app.ClassificationEditField.Value = char(classification);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AuthorizeMeButton = uibutton(app.UIFigure, 'push');
            app.AuthorizeMeButton.ButtonPushedFcn = createCallbackFcn(app, @AuthorizeMeButtonPushed, true);
            app.AuthorizeMeButton.Text = 'Authorize Me';
            app.SuccesfulAuthorizationEditFieldLabel = uilabel(app.UIFigure);
            app.SuccesfulAuthorizationEditFieldLabel.Text = 'Succesful Authorization %';
            app.SuccesfulAuthorizationEditField = uieditfield(app.UIFigure, 'numeric');
            app.SuccesfulAuthorizationEditField.AllowEmpty = 'on';
            app.SuccesfulAuthorizationEditField.Editable = 'off';
            app.SuccesfulAuthorizationEditField.Value = [];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.TrainModelButton = uibutton(app.UIFigure, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Text = 'Train Model';
            app.ModelTrainedLabel = uilabel(app.UIFigure);
            app.ModelTrainedLabel.Text = 'Model Trained';
            app.ClassificationEditFieldLabel = uilabel(app.UIFigure);
            app.ClassificationEditFieldLabel.Text = 'Classification';
            app.ClassificationEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Person_Identification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end